classdef CheckNeuron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        ScaleMovieEditField          matlab.ui.control.NumericEditField
        ScaleMovieEditFieldLabel     matlab.ui.control.Label
        DrawExtraFootprintCheckBox   matlab.ui.control.CheckBox
        PathtoSessResEditField       matlab.ui.control.EditField
        PathtoSessResEditFieldLabel  matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        ListBoxLabel                 matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        GoodLamp                     matlab.ui.control.Lamp
        GoodLampLabel                matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        BadButton                    matlab.ui.control.Button
        GoodButton                   matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        JumptoFrameSpinner           matlab.ui.control.Spinner
        JumptoFrameSpinnerLabel      matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        NeuTraceMat % Description
        footprint
        max_proj
        dpath; ftpath; maxprojpath;tracepath;
        qualityMetrics
        itemnow
        qmpath
        externalfp
        movpath; vidobj; currFrameNum;

        cellbounds
        hl; h_circle
        scale = 7;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoodButton
        function GoodButtonPushed(app, event)
            app.qualityMetrics(app.itemnow) = 1;
            app.GoodLamp.Color = 'g';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item; % item is the number you choose
            app.itemnow = item;
            try
                close FootprintNow
            end
            cla(app.UIAxes2)
            if app.qualityMetrics(item)==1
                app.GoodLamp.Color = 'g';
            elseif app.qualityMetrics(item)==0
                app.GoodLamp.Color = 'r';
            else
                app.GoodLamp.Color = 'y';
            end

            plot(app.UIAxes,app.NeuTraceMat(item,:),'LineWidth',1)

            
            axes(app.UIAxes2)
            imshow(app.max_proj/100,'Parent',app.UIAxes2,'InitialMagnification','fit')
            hold(app.UIAxes2,'on')
            %axis(app.UIAxes2,'ij')
            B = app.cellbounds;
            for UId = 1:length(B)
                boundary = B{UId};
                if UId == item
                    plot(app.UIAxes2,boundary(:,2),boundary(:,1),'g','LineWidth',0.8)
                    hold(app.UIAxes2,'on');
                    % hj1 = fill(app.UIAxes2,boundary(:,2),boundary(:,1),'r');
                    % set(hj1,'facealpha',0.7)
                else
                    plot(app.UIAxes2,boundary(:,2),boundary(:,1),'r','LineWidth',0.5)
                    hold(app.UIAxes2,'on');
                    % hj1 = fill(app.UIAxes2,boundary(:,2),boundary(:,1),'r');
                    % set(hj1,'facealpha',0.1)
                end
            end
            if app.externalfp == 1
                figure('Name','FootprintNow','NumberTitle','off')
                for UId = 1:length(B)
                    boundary = B{UId};

                    plot(boundary(:,2),size(bwmap,2)-boundary(:,1),'r','LineWidth',0.5)
                    hold on
                    if UId == item
                        hj1 = fill(boundary(:,2),size(bwmap,2)-boundary(:,1),'r');
                        set(hj1,'facealpha',0.7)
                    else
                        hj1 = fill(boundary(:,2),size(bwmap,2)-boundary(:,1),'r');
                        set(hj1,'facealpha',0.1)
                    end
                end
            end
            axes(app.UIAxes3)
            hold(app.UIAxes3,'on')
            try
                delete(app.h_circle)
                boundary = B{app.itemnow};
                app.h_circle = plot(app.UIAxes3,boundary(:,2),boundary(:,1),'g','LineWidth',0.8);
            catch
                boundary = B{app.itemnow};
                app.h_circle = plot(app.UIAxes3,boundary(:,2),boundary(:,1),'g','LineWidth',0.8);
            end


        end

        % Value changed function: PathtoSessResEditField
        function PathtoSessResEditFieldValueChanged(app, event)
            value = app.PathtoSessResEditField.Value;
            app.dpath = value;
            allfile = dir(app.dpath);
            for i = 1:length(allfile)
                if contains(allfile(i).name,'A_add.mat') && ~contains(allfile(i).name,'Chose')
                    app.ftpath = [value,'\',allfile(i).name];
                end
                if contains(allfile(i).name,'NeuTrace')
                    app.tracepath = [value,'\',allfile(i).name];
                end
                if contains(allfile(i).name,'max_proj')
                    app.maxprojpath = [value,'\',allfile(i).name];
                end
                if contains(allfile(i).name,'qualityMetrics')
                    app.qmpath = [value,'\',allfile(i).name];
                end
                if contains(allfile(i).name,'minian_all')
                    app.movpath = [value,'\',allfile(i).name];
                end
            end
            disp(['Loading ',app.ftpath])
            load(app.ftpath)
            try
                app.footprint = array;
            catch
                app.footprint = array2save;
            end
            area = app.footprint;

            B = cell(size(area,1),1);
            %centroid = zeros(size(area,1),2);
            for umap_id = 1:size(area,1)
                single_map = squeeze(area(umap_id,:,:));
                bwmap = im2bw(single_map);
                [b,L] = bwboundaries(bwmap,'noholes');
                %stats = regionprops(L,'Area','Centroid');
                B{umap_id} = b{1};
                %cent = stats(1).Centroid;
                %centroid(umap_id,:) = cent;
            end
            app.cellbounds = B;

            disp(['Loading ',app.tracepath])
            trace = load(app.tracepath);
            app.NeuTraceMat = trace.NeuTraceMat;
            disp(['Loading ',app.maxprojpath])
            load(app.maxprojpath)
            app.max_proj = array;
            if ~isempty(app.qmpath)
                load(app.qmpath)
                app.qualityMetrics = qm;
                if length(qm) ~= size(app.NeuTraceMat,1)
                    app.qualityMetrics = [];
                end
            else
                app.qualityMetrics = [];
            end

            NeuNum = size(app.NeuTraceMat,1);
            app.ListBox.Items = {};
            if isempty(app.qualityMetrics)
                for i = 1:NeuNum
                    app.ListBox.Items{end+1} = ['Unit',num2str(i)];
                end
                app.qualityMetrics = 2*ones(1,NeuNum);
            else
                for i = 1:NeuNum
                    app.ListBox.Items{end+1} = ['Unit',num2str(i)];
                end
            end

            vidx = VideoReader(app.movpath);
            firstFrame = read(vidx,1);
            app.vidobj = vidx;
            axes(app.UIAxes3)
            imshow(firstFrame*app.scale,'Parent',app.UIAxes3,'InitialMagnification','fit')
            

        end

        % Button pushed function: BadButton
        function BadButtonPushed(app, event)
            app.qualityMetrics(app.itemnow) = 0;
            app.GoodLamp.Color = 'r';

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            qm = app.qualityMetrics;
            save([app.dpath,'\qualityMetrics.mat'],'qm')
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.currFrameNum = ceil(value/100*app.vidobj.NumFrames);
            framenow = read(app.vidobj,app.currFrameNum);
            axes(app.UIAxes3)
            imshow(framenow*app.scale,'Parent',app.UIAxes3,'InitialMagnification','fit')
            set(app.UIAxes3,'visible','off')
            hold(app.UIAxes3,'on')

            B = app.cellbounds;
            for UId = 1:length(B)
                boundary = B{UId};
                if UId == app.itemnow
                    plot(app.UIAxes3,boundary(:,2),boundary(:,1),'g','LineWidth',0.8)
                end
            end

            axes(app.UIAxes)
            hold on
            try 
                delete(app.hl)
                app.hl = line(app.UIAxes,[app.currFrameNum app.currFrameNum],[-2,5],'LineWidth',1,'color','k');
            catch
                app.hl = line(app.UIAxes,[app.currFrameNum app.currFrameNum],[-2,5],'LineWidth',1,'color','k');
            end


        end

        % Value changed function: DrawExtraFootprintCheckBox
        function DrawExtraFootprintCheckBoxValueChanged(app, event)
            value = app.DrawExtraFootprintCheckBox.Value;
            if isempty(value)
                value = 0;
            end
            app.externalfp = value;
        end

        % Value changed function: JumptoFrameSpinner
        function JumptoFrameSpinnerValueChanged(app, event)
            value = app.JumptoFrameSpinner.Value;
            app.currFrameNum = value;
            if value < app.vidobj.NumFrames
                framenow = read(app.vidobj,app.currFrameNum);
                axes(app.UIAxes3)
                imshow(framenow*app.scale,'Parent',app.UIAxes3,'InitialMagnification','fit')
            else
                warndlg('Please enter a number less than your maximum frames')
            end
            axes(app.UIAxes3)
            imshow(framenow,'Parent',app.UIAxes3,'InitialMagnification','fit')
            set(app.UIAxes3,'visible','off')
            hold(app.UIAxes3,'on')

            B = app.cellbounds;
            for UId = 1:length(B)
                boundary = B{UId};
                if UId == app.itemnow
                    plot(app.UIAxes3,boundary(:,2),boundary(:,1),'g','LineWidth',0.8)
                    hold(app.UIAxes3,'on');
                end
            end

            axes(app.UIAxes)
            hold on
            try 
                delete(app.hl)
                app.hl = line(app.UIAxes,[app.currFrameNum app.currFrameNum],[-2,5],'LineWidth',1,'color','k');
            catch
                app.hl = line(app.UIAxes,[app.currFrameNum app.currFrameNum],[-2,5],'LineWidth',1,'color','k');
            end
        end

        % Value changed function: ScaleMovieEditField
        function ScaleMovieEditFieldValueChanged(app, event)
            value = app.ScaleMovieEditField.Value;
            app.scale = value;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            value = changingValue;
            % app.currFrameNum = ceil(value/100*app.vidobj.NumFrames);
            % framenow = read(app.vidobj,app.currFrameNum);
            % axes(app.UIAxes3)
            % imshow(framenow*app.scale,'Parent',app.UIAxes3,'InitialMagnification','fit')
            % drawnow
            % %set(app.UIAxes3,'visible','off')
            % hold(app.UIAxes3,'on')
            % 
            % B = app.cellbounds;
            % for UId = 1:length(B)
            %     boundary = B{UId};
            %     if UId == app.itemnow
            %         plot(app.UIAxes3,boundary(:,2),boundary(:,1),'g','LineWidth',0.8)
            %         drawnow
            %     end
            % end
            % 
            % axes(app.UIAxes)
            % hold on
            % try 
            %     delete(app.hl)
            %     app.hl = line(app.UIAxes,[app.currFrameNum app.currFrameNum],[-2,5],'LineWidth',1,'color','k');
            % catch
            %     app.hl = line(app.UIAxes,[app.currFrameNum app.currFrameNum],[-2,5],'LineWidth',1,'color','k');
            % end
            % drawnow
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {637, 637, 637};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {637, 637};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {244, '1x', 739};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ListBoxLabel = uilabel(app.GridLayout2);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.PathtoSessResEditFieldLabel = uilabel(app.GridLayout2);
            app.PathtoSessResEditFieldLabel.Text = 'Path to SessRes';
            app.PathtoSessResEditField = uieditfield(app.GridLayout2, 'text');
            app.PathtoSessResEditField.ValueChangedFcn = createCallbackFcn(app, @PathtoSessResEditFieldValueChanged, true);
            app.DrawExtraFootprintCheckBox = uicheckbox(app.GridLayout2);
            app.DrawExtraFootprintCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawExtraFootprintCheckBoxValueChanged, true);
            app.DrawExtraFootprintCheckBox.Text = 'Draw Extra Footprint';
            app.ScaleMovieEditFieldLabel = uilabel(app.GridLayout2);
            app.ScaleMovieEditFieldLabel.Text = 'Scale Movie';
            app.ScaleMovieEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ScaleMovieEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleMovieEditFieldValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.GoodButton = uibutton(app.GridLayout3, 'push');
            app.GoodButton.ButtonPushedFcn = createCallbackFcn(app, @GoodButtonPushed, true);
            app.GoodButton.Text = 'Good';
            app.BadButton = uibutton(app.GridLayout3, 'push');
            app.BadButton.ButtonPushedFcn = createCallbackFcn(app, @BadButtonPushed, true);
            app.BadButton.Text = 'Bad';
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GoodLampLabel = uilabel(app.GridLayout3);
            app.GoodLampLabel.Text = 'Good？';
            app.GoodLamp = uilamp(app.GridLayout3);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Trace')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Max Projection')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Video')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SliderLabel = uilabel(app.GridLayout4);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout4);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.JumptoFrameSpinnerLabel = uilabel(app.GridLayout4);
            app.JumptoFrameSpinnerLabel.Text = 'Jump to Frame';
            app.JumptoFrameSpinner = uispinner(app.GridLayout4);
            app.JumptoFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @JumptoFrameSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckNeuron

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end