classdef viewFPevent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ExportButton                    matlab.ui.control.Button
        UseDataSwitch                   matlab.ui.control.Switch
        UseDataSwitchLabel              matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        FrameSpinnerLabel               matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        BehaviorVideopathEditField      matlab.ui.control.EditField
        BehaviorVideopathEditFieldLabel  matlab.ui.control.Label
        FPorigindatapathEditField       matlab.ui.control.EditField
        FPorigindatapathEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        Oripath; DFFpath;
        dFFdata; dFFtime; ori405; ori470;
        ds405; ds470;
        dsidx; dsDffData; dsDffTime;
        Vidpath; Vidtimepath; vidtime

        readobj; currFrameNum; traceidx
        hl

        useDff = 1

        framenum
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FPorigindatapathEditField
        function FPorigindatapathEditFieldValueChanged(app, event)
            value = app.FPorigindatapathEditField.Value;
            if strcmp(value(1),'"')
                value = value(2:end-1);
            end
            if contains(value,'DFF')
                value = [value(1:end-10),'.avi'];
            end
            app.Oripath = value;
            app.DFFpath = [app.Oripath(1:end-6),'_DFF.doric'];
            app.dFFdata = h5read(app.DFFpath,'/DataProcessed/FPConsole/DFFSignals/Series0001/LockInAOUT02/AIN01');
            app.dFFtime = h5read(app.DFFpath,'/DataProcessed/FPConsole/DFFSignals/Series0001/LockInAOUT02/Time');
            app.ori405 = h5read(app.Oripath,'/DataAcquisition/FPConsole/Signals/Series0001/LockInAOUT01/AIN01');
            app.ori470 = h5read(app.Oripath,'/DataAcquisition/FPConsole/Signals/Series0001/LockInAOUT02/AIN01');

            app.dsidx = 1:2:length(app.dFFtime);
            app.dsDffData = app.dFFdata(app.dsidx); app.dsDffTime = app.dFFtime(app.dsidx);
            app.ds405 = app.ori405(app.dsidx); 
            app.ds470 = app.ori470(app.dsidx);
            if app.useDff
                plot(app.UIAxes2,app.dsDffData,'LineWidth',1)
            else
                plot(app.UIAxes2,app.ds405,'LineWidth',1,'Color','r')
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,max(app.ds405)+app.ds470,'LineWidth',1,'Color','b')
            end

            
        end

        % Value changed function: BehaviorVideopathEditField
        function BehaviorVideopathEditFieldValueChanged(app, event)
            value = app.BehaviorVideopathEditField.Value;
            if strcmp(value(1),'"')
                value = value(2:end-1);
            end
            if contains(value,'.doric')
                value = [value(1:end-6),'.avi'];
            end
            app.Vidpath = value;
            app.readobj = VideoReader(app.Vidpath);
            app.Vidtimepath = [app.Vidpath(1:end-4),'.doric'];
            app.vidtime = h5read(app.Vidtimepath,'/DataAcquisition/BehaviorCamera/Video/Series0001/Logitech-Webcam-C925e-1/Time');
            
            framenow = readFrame(app.readobj);
            app.framenum = app.readobj.NumFrames;
            app.Slider.Limits = [1 app.framenum];
            axes(app.UIAxes)
            imshow(framenow,'Parent',app.UIAxes,'InitialMagnification','fit')
            set(app.UIAxes,'visible','off')
            hold(app.UIAxes,'on')
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.currFrameNum = ceil(value/max(app.Slider.Limits)*app.readobj.NumFrames);
            framenow = read(app.readobj,app.currFrameNum);
            axes(app.UIAxes)
            imshow(framenow,'Parent',app.UIAxes,'InitialMagnification','fit')
            set(app.UIAxes,'visible','off')
            hold(app.UIAxes,'on')

            
            hold(app.UIAxes2,'on')
            [~,app.traceidx] = min(abs(app.dsDffTime - app.vidtime(app.currFrameNum)));
            if app.useDff
                try
                    delete(app.hl)
                    app.hl = line(app.UIAxes2,[app.traceidx app.traceidx],[min(app.dFFdata),max(app.dFFdata)],'LineWidth',1,'color','k');
                catch
                    app.hl = line(app.UIAxes2,[app.traceidx app.traceidx],[min(app.dFFdata),max(app.dFFdata)],'LineWidth',1,'color','k');
                end
            else
                try
                    delete(app.hl)
                    app.hl = line(app.UIAxes2,[app.traceidx app.traceidx],[min(app.ds405),max(app.ds470)],'LineWidth',1,'color','k');
                catch
                    app.hl = line(app.UIAxes2,[app.traceidx app.traceidx],[min(app.ds405),max(app.ds470)],'LineWidth',1,'color','k');
                end
            end
        end

        % Value changed function: UseDataSwitch
        function UseDataSwitchValueChanged(app, event)
            value = app.UseDataSwitch.Value;
            if strcmp(value,'dFF')
                app.useDff = 1;
            else
                app.useDff = 0;
            end
            if app.useDff
                hold(app.UIAxes2,'off')
                plot(app.UIAxes2,app.dsDffData,'LineWidth',1)
            else
                hold(app.UIAxes2,'off')
                plot(app.UIAxes2,app.ds405,'LineWidth',1,'Color','r')
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,max(app.ds405)+app.ds470,'LineWidth',1,'Color','b')
            end
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            app.currFrameNum = value;
            framenow = read(app.readobj,app.currFrameNum);
            axes(app.UIAxes)
            imshow(framenow,'Parent',app.UIAxes,'InitialMagnification','fit')
            set(app.UIAxes,'visible','off')
            hold(app.UIAxes,'on')

            
            hold(app.UIAxes2,'on')
            [~,app.traceidx] = min(abs(app.dsDffTime - app.vidtime(app.currFrameNum)));
            try 
                delete(app.hl)
                app.hl = line(app.UIAxes2,[app.traceidx app.traceidx],[min(app.dFFdata),max(app.dFFdata)],'LineWidth',1,'color','k');
            catch
                app.hl = line(app.UIAxes2,[app.traceidx app.traceidx],[min(app.dFFdata),max(app.dFFdata)],'LineWidth',1,'color','k');
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            csv2write = [app.dFFtime, app.dFFdata];
            mkdir([app.Oripath(1:end-6),'Exported/'])
            writematrix(csv2write,[app.Oripath(1:end-6),'Exported/','#','dFF',' ','.csv'])
            csv2write = [app.dFFtime, app.ori405, app.ori470];
            writematrix(csv2write,[app.Oripath(1:end-6),'Exported/','#','OriginData',' ','.csv'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FPorigindatapathEditFieldLabel = uilabel(app.GridLayout);
            app.FPorigindatapathEditFieldLabel.Text = 'FP origin data path';
            app.FPorigindatapathEditField = uieditfield(app.GridLayout, 'text');
            app.FPorigindatapathEditField.ValueChangedFcn = createCallbackFcn(app, @FPorigindatapathEditFieldValueChanged, true);
            app.BehaviorVideopathEditFieldLabel = uilabel(app.GridLayout);
            app.BehaviorVideopathEditFieldLabel.Text = 'Behavior Video path';
            app.BehaviorVideopathEditField = uieditfield(app.GridLayout, 'text');
            app.BehaviorVideopathEditField.ValueChangedFcn = createCallbackFcn(app, @BehaviorVideopathEditFieldValueChanged, true);
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FrameSpinnerLabel = uilabel(app.GridLayout);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.GridLayout);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.UseDataSwitchLabel = uilabel(app.GridLayout);
            app.UseDataSwitchLabel.Text = 'Use Data';
            app.UseDataSwitch = uiswitch(app.GridLayout, 'slider');
            app.UseDataSwitch.Items = {'dFF', 'Origin'};
            app.UseDataSwitch.ValueChangedFcn = createCallbackFcn(app, @UseDataSwitchValueChanged, true);
            app.UseDataSwitch.Value = 'dFF';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewFPevent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end